classdef DIPProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        SubmittedbyNaveedAhmedLabel     matlab.ui.control.Label
        InstructorSirGhulamMurtazaLabel  matlab.ui.control.Label
        SemesterProjectLabel            matlab.ui.control.Label
        DigitalImageProcessingLabel     matlab.ui.control.Label
        INTERPOLATIONTab                matlab.ui.container.Tab
        DigitalImageProcessiongLabel    matlab.ui.control.Label
        REMOVENOISEButton               matlab.ui.control.Button
        ADDNOISEButton                  matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
        BICUBICButton                   matlab.ui.control.Button
        BILINEARButton                  matlab.ui.control.Button
        AllOperationsCombinedButton     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        NEARESTButton                   matlab.ui.control.Button
        FILTERINGTab                    matlab.ui.container.Tab
        DigitalImageProcessiongLabel_2  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        GaussianfilterButton            matlab.ui.control.Button
        WeightedFilterButton            matlab.ui.control.Button
        BoxFilterButton                 matlab.ui.control.Button
        StrethcedImageButton            matlab.ui.control.Button
        FiltersCombinedButton           matlab.ui.control.Button
        HISTOGRAMTab                    matlab.ui.container.Tab
        DigitalImageProcessiongLabel_3  matlab.ui.control.Label
        AllOperationsButton             matlab.ui.control.Button
        Image4                          matlab.ui.control.Image
        HISTOGRAMButton                 matlab.ui.control.Button
        MATCHINGButton                  matlab.ui.control.Button
        EQUALIZATIONButton              matlab.ui.control.Button
        RESTORATIONTab                  matlab.ui.container.Tab
        DigitalImageProcessiongLabel_4  matlab.ui.control.Label
        RemoveNoiseButton               matlab.ui.control.Button
        SaltandPepperButton             matlab.ui.control.Button
        Image6                          matlab.ui.control.Image
        GaussianNoiseButton             matlab.ui.control.Button
        FREQUENCEANDSPATIALDOMAINTab    matlab.ui.container.Tab
        DigitalImageProcessiongLabel_5  matlab.ui.control.Label
        SOBELButton                     matlab.ui.control.Button
        PREWITTButton                   matlab.ui.control.Button
        MOTIONButton                    matlab.ui.control.Button
        LAPLACIANButton                 matlab.ui.control.Button
        DISKButton                      matlab.ui.control.Button
        AVERAGEButton                   matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        LowPassFiltersANDHighPassFiltersLabel  matlab.ui.control.Label
        LOGButton                       matlab.ui.control.Button
        Image5                          matlab.ui.control.Image
        IMAGESHARPENINGButton           matlab.ui.control.Button
        LPFGAUSSIANButton               matlab.ui.control.Button
        SPATIALDOMAINButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: NEARESTButton
        function NEARESTButtonPushed(app, event)
                clear
                img = imread('original_image.tif');
                nn = imresize(img,0.3,'nearest'); %shrinking by 30%
                figure,subplot(2,2,1),imshow(img), title('Original Image');
                subplot(2,2,2),imshow(nn), title('Nearest neighbor interpolation');
        end

        % Button pushed function: FiltersCombinedButton
        function FiltersCombinedButtonPushed(app, event)
            
            %image = imread('satellite_Image.gif');
            
            pic1=imread('pout.tif');
            
            BoxFilter = conv2(single(pic1), ones(3)/9, 'same');
            WtFilter = conv2(single(pic1), [1,2,1;2,4,2;1,2,1]/16, 'same');
            GaussianFilter = imgaussfilt(pic1,2); % with std dev=2, 'FilterSize’,3 (i.e. k)   
            
            % or imshow(uint8(blurredImage), [0 255]);
            figure,subplot(2,2,1),imshow(pic1, []);title('Orginal image')
            subplot(2,2,2),imshow(BoxFilter, []); title('Box filter image')
            subplot(2,2,3),imshow(WtFilter, []); title('Weighted filter image');
            subplot(2,2,4),imshow(GaussianFilter, []); title('Gaussian filter image');
        end

        % Button pushed function: StrethcedImageButton
        function StrethcedImageButtonPushed(app, event)
            image = imread('pout.tif');
            LowHigh = stretchlim(image, [0.05, 0.95]); %To avoid outliers use 5% Q1 and 95% Q2
            stretched_Image = imadjust(image, LowHigh,[]); %Adjust image intensity values or colormap, [] for linear maping
             
            figure,subplot(1,2,1), imshow(image), title('Original Image');
            subplot(1,2,2), imshow(stretched_Image),title('Strethced Image');
        end

        % Button pushed function: BoxFilterButton
        function BoxFilterButtonPushed(app, event)
            pic1=imread('pout.tif');
            BoxFilter = conv2(single(pic1), ones(3)/9, 'same');
            % or imshow(uint8(blurredImage), [0 255]);
            subplot(1,2,1),imshow(pic1, []);title('Orginal image');
            subplot(1,2,2),imshow(BoxFilter, []); title('Box filter image');
        end

        % Button pushed function: WeightedFilterButton
        function WeightedFilterButtonPushed(app, event)
            pic1=imread('pout.tif');
            
           
            WtFilter = conv2(single(pic1), [1,2,1;2,4,2;1,2,1]/16, 'same');
            
            % or imshow(uint8(blurredImage), [0 255]);
            figure,subplot(1,2,1),imshow(pic1, []);title('Orginal image')
            subplot(1,2,2),imshow(WtFilter, []); title('Weighted filter image')
            
        end

        % Button pushed function: GaussianfilterButton
        function GaussianfilterButtonPushed(app, event)
            pic1=imread('pout.tif');
            
            GaussianFilter = imgaussfilt(pic1,2); % with std dev=2, 'FilterSize’,3 (i.e. k)   
            
            % or imshow(uint8(blurredImage), [0 255]);
            figure,subplot(1,2,1),imshow(pic1, []);title('Orginal image')
            subplot(1,2,2),imshow(GaussianFilter, []); title('Gaussian filter image')

        end

        % Button pushed function: AllOperationsCombinedButton
        function AllOperationsCombinedButtonPushed(app, event)
                clear
                img = imread('original_image.tif');
                nn = imresize(img,0.3,'nearest'); %shrinking by 30%
                bl = imresize(img,0.3,'bilinear'); 
                bc = imresize(img,0.3,'bicubic'); 
                figure,subplot(2,2,1),imshow(img), title('Original Image');
                subplot(2,2,2),imshow(nn), title('Nearest neighbor interpolation');
                subplot(2,2,3),imshow(bl), title('bilinear interpolation');
                subplot(2,2,4),imshow(bc), title('bicubic interpolation');

        end

        % Button pushed function: BILINEARButton
        function BILINEARButtonPushed(app, event)
                clear
                img = imread('original_image.tif');
                bl = imresize(img,0.3,'bilinear'); 
                figure,subplot(2,2,1),imshow(img), title('Original Image');
                subplot(2,2,2),imshow(bl), title('bilinear interpolation');
        end

        % Button pushed function: BICUBICButton
        function BICUBICButtonPushed(app, event)
                clear
                img = imread('original_image.tif');
                bc = imresize(img,0.3,'bicubic'); 
                figure,subplot(2,2,1),imshow(img), title('Original Image');
                subplot(2,2,2),imshow(bc), title('bicubic interpolation');
        end

        % Button pushed function: ADDNOISEButton
        function ADDNOISEButtonPushed(app, event)
            clc
            close all
            I = imread('eight.tif'); 
            J = imnoise(I,'salt & pepper',0.02);
            imshow(J),title('Noisy image SALT & PEPPER')
        end

        % Button pushed function: REMOVENOISEButton
        function REMOVENOISEButtonPushed(app, event)
            clc
            close all
            I = imread('eight.tif'); 
            figure, subplot(2,2,1),imshow(I),title('orginal image')
            J = imnoise(I,'salt & pepper',0.02);
            subplot(2,2,2),imshow(J),title('Noisy image SALT & PEPPER')
            Kmedian = medfilt2(J);
            subplot(2,2,3),imshow(Kmedian),
            title('Noise removed image')
        end

        % Button pushed function: HISTOGRAMButton
        function HISTOGRAMButtonPushed(app, event)
            clear
            clc
            I = imread('tire.tif');
            J = histeq(I);
            figure,
            subplot(1,2,1),imshow(I), title('Original image');
            subplot(1,2,2),imhist(I,64); title('Oruginal image histogram');
        end

        % Button pushed function: EQUALIZATIONButton
        function EQUALIZATIONButtonPushed(app, event)
            clear
            clc
            I = imread('tire.tif');
            J = histeq(I);
            figure,
            subplot(2,2,1),imshow(I), title('Orinal image')
            subplot(2,2,2),imshow(J), title('Histogram Equalized image')
            subplot(2,2,3),imhist(I,64); title('Org image histogram')
            subplot(2,2,4),imhist(J,64);title('Equalized histogram')
        end

        % Button pushed function: MATCHINGButton
        function MATCHINGButtonPushed(app, event)
            clear
            A = imread('concordaerial.png');
            Ref = imread('tire.tif');
            B = imhistmatch(A,Ref);
            figure, 
            subplot(2,2,1),imshow(A),title('RGB Image with Color Cast')
            subplot(2,2,2),imshow(B), title('Histogram Matched RGB Image')
            subplot(2,2,3),imshow(Ref), title('Reference Grayscale Image')

        end

        % Button pushed function: GaussianNoiseButton
        function GaussianNoiseButtonPushed(app, event)
            clear all
            clc
            global NoisyImage;
            OrgImg = imread('cameraman.tif');
            %adds Gaussian white noise with mean m and variance var_gauss.
            %J = imnoise(I,'gaussian',m,var_gauss) 
            %default m=0 and var_gauss=0.1
            NoisyImage = imnoise(OrgImg,'gaussian',0.02,0.2);
            figure
            subplot(1,2,1);imshow(OrgImg),title("Original Image");
            subplot(1,2,2);imshow(NoisyImage),title("Gaussian Noise");
            %figure, imhist(OrgImg)
            %figure, imhist(NoisyImg)
 
        end

        % Button pushed function: SPATIALDOMAINButton
        function SPATIALDOMAINButtonPushed(app, event)
            clc
            clear all
            close all
            %resmple an image to study the effects of unsesampling
            img1 = imread ('football.PNG');
            figure('Renderer', 'painters', 'Position', [10 10 900 600])
            subplot(3,3,1),imshow(img1),title('Orginal image');
            [H,W,C] = size(img1);
            %% Downsample by factor of M but no filtering
            %1- Downsample the Orginal image by factor 4 in x,y
            %2- Resize the downsampled image
            M=4;
            img3 = img1(1:M:H,1:M:W);
            subplot(3,3,3),imshow(img3);title('Downsampled by 4 in x,y directions, Without filtering');
            %Reconstructed to orginal size
            img4= imresize(img3,[H,W],'bicubic');
            subplot(3,3,4),imshow(img4); title('Ressized downsampled image, Without filtering');
            %% Downsample by factor of M after filtering
            %1- Smoothen the orginal image by guassian filter
            %2- Downsample the filtered image by 4 in x,y
            %3- Resize the downsampled image
            GaussianFilter = fspecial('gaussian',[9 9],1); % Use a guassian filter
            img5 = imfilter(img1,GaussianFilter,'symmetric','same');
            img6 = img5(1:M:H,1:M:W);
            subplot(3,3,7),imshow(img6),title('Downsampled by 4 in x,y directions, With filtering');
            img7 = imresize(img6,[H,W],'bicubic');
            subplot(3,3,9),imshow(img7);title('Resized downsampled image, With filtering');

        end

        % Button pushed function: LPFGAUSSIANButton
        function LPFGAUSSIANButtonPushed(app, event)
            I=imread("football.jpg")
            figure,subplot(1,2,1),imshow(I),title("Original Image");
            MotionBlur = imfilter(I,h,'replicate');
            subplot(1,2,2),imshow(MotionBlur),title("Gaussian Filter");
        end

        % Button pushed function: IMAGESHARPENINGButton
        function IMAGESHARPENINGButtonPushed(app, event)
            clear cll;close all;clc
            % MATLAB Code | Ideal High Pass Filter  
            % Reading input image : input_image 
            input_image=rgb2gray(imread('football.PNG'));
            [M, N] = size(input_image);
            % Getting Fourier Transform of the input_image
            % using MATLAB library function fft2 (2D fast fourier transform)  
            FT_img = fft2(double(input_image));
            % Assign Cut-off Frequency  
            D0 = 10; % one can change this value accordingly
            % Designing filter
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;
            % MATLAB library function meshgrid(v, u) returns 2D grid
            %  which contains the coordinates of vectors v and u. 
            % Matrix V with each row is a copy of v, and matrix U 
            % with each column is a copy of u
            [V, U] = meshgrid(v, u);
            % Calculating Euclidean Distance
            D = sqrt(U.^2+V.^2);
            % Comparing with the cut-off frequency and 
            % determining the filtering mask
            H = double(D > D0);
            % Convolution between the Fourier Transformed image and the mask
            G = H.*FT_img;
            % Getting the resultant image by Inverse Fourier Transform
            % of the convoluted image using MATLAB library function
            % ifft2 (2D inverse fast fourier transform)  
            output_image = real(ifft2(double(G)));
            % Displaying Input Image and Output Image
            subplot(1, 2, 1), imshow(input_image), 
            title ('Orginal Image')
            subplot(1, 2, 2), imshow(output_image, [ ]);
            title ('IDEAL Highpass filter')

        end

        % Button pushed function: LOGButton
        function LOGButtonPushed(app, event)
            I=imread("football.jpg");
            h = fspecial('log',40,10);
            figure,subplot(1,2,1),imshow(I);
            average = imfilter(I,h,'replicate');
            subplot(1,2,2),imshow(average);
        end

        % Callback function
        function RaylieghNoiseButtonPushed(app, event)
            clear all
            clc
            OrgImg = imread('cameraman.tif');
            %adds Gaussian white noise with mean m and variance var_gauss.
            %J = imnoise(I,'gaussian',m,var_gauss) 
            %default m=0 and var_gauss=0.1
            NoisyImg = imnoise(OrgImg,'rayliegh',0.02,0.2);
            figure
            subplot(2,2,1);imshow(OrgImg);
            subplot(2,2,3);imhist(OrgImg);
            subplot(2,2,2);imshow(NoisyImg);
            subplot(2,2,4);imhist(NoisyImg);
        end

        % Button pushed function: SaltandPepperButton
        function SaltandPepperButtonPushed(app, event)
            clc
            close all
            global NoisyImage;
            I = imread('cameraman.tif'); 
            NoisyImage = imnoise(I,'salt & pepper',0.02);
            figure,subplot(1,2,1),imshow(I),title("Original Image");
            subplot(1,2,2),imshow(NoisyImage),title('Noisy image SALT & PEPPER')
        end

        % Button pushed function: AllOperationsButton
        function AllOperationsButtonPushed(app, event)
            clear
            A = imread('concordaerial.png');
            Ref = imread('tire.tif');
            B = imhistmatch(A,Ref);
            figure('Renderer', 'painters', 'Position', [10 10 900 600]), 
            subplot(3,3,1),imshow(A),title('RGB Image with Color Cast')
            subplot(3,3,2),imshow(B), title('Histogram Matched RGB Image')
            subplot(3,3,3),imshow(Ref), title('Reference Grayscale Image')
            
            I = imread('tire.tif');
            J = histeq(I);
            subplot(3,3,4),imshow(I), title('Original image')
            subplot(3,3,5),imshow(J), title('Histogram Equalized image')
            subplot(3,3,7),imhist(I,64); title('Original image histogram')
            subplot(3,3,8),imhist(J,64);title('Equalized histogram')
        end

        % Button pushed function: AVERAGEButton
        function AVERAGEButtonPushed(app, event)
            I=imread("football.jpg")
            h = fspecial('average',2)
            figure,subplot(1,2,1),imshow(I);
            average = imfilter(I,h,'replicate');
            subplot(1,2,2),imshow(average);
        end

        % Button pushed function: DISKButton
        function DISKButtonPushed(app, event)
            I=imread("football.jpg")
            h = fspecial('disk',5);
            figure,subplot(1,2,1),imshow(I);
            average = imfilter(I,h,'replicate');
            subplot(1,2,2),imshow(average);
        end

        % Button pushed function: LAPLACIANButton
        function LAPLACIANButtonPushed(app, event)
            I=imread("football.jpg")
         
            h = fspecial('laplacian',0.2)
            figure,subplot(1,2,1),imshow(I);
            average = imfilter(I,h,'replicate');
            subplot(1,2,2),imshow(average);
        end

        % Button pushed function: MOTIONButton
        function MOTIONButtonPushed(app, event)
            I=imread("football.jpg");
            h = fspecial('motion',40,45);
            figure,subplot(1,2,1),imshow(I);
            average = imfilter(I,h,'replicate');
            subplot(1,2,2),imshow(average);
        end

        % Button pushed function: PREWITTButton
        function PREWITTButtonPushed(app, event)
            I=imread("football.jpg");
            h = fspecial('prewitt');
            figure,subplot(1,2,1),imshow(I);
            average = imfilter(I,h,'replicate');
            subplot(1,2,2),imshow(average);
        end

        % Button pushed function: SOBELButton
        function SOBELButtonPushed(app, event)
            I=imread("football.jpg")
            %h = fspecial('sobel')
            figure,subplot(1,2,1),imshow(I);
            average = imfilter(I,h,'replicate');
            subplot(1,2,2),imshow(average);
        end

        % Button pushed function: RemoveNoiseButton
        function RemoveNoiseButtonPushed(app, event)
            global NoisyImage;
            Kmedian = medfilt2(NoisyImage);
            figure 
            subplot(1,2,1),imshow(NoisyImage),title("Noisy Image");
            subplot(1,2,2),imshow(Kmedian),title("Original image");
        end

        % Callback function
        function RemoveNoiseButton_2Pushed(app, event)
            global slat_pepper;
            imshow(slat_pepper);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home Tab';
            app.DigitalImageProcessingLabel = uilabel(app.HomeTab);
            app.DigitalImageProcessingLabel.Text = 'Digital Image Processing';
            app.SemesterProjectLabel = uilabel(app.HomeTab);
            app.SemesterProjectLabel.Text = 'Semester Project';
            app.InstructorSirGhulamMurtazaLabel = uilabel(app.HomeTab);
            app.InstructorSirGhulamMurtazaLabel.Text = 'Instructor:   Sir Ghulam Murtaza';
            app.SubmittedbyNaveedAhmedLabel = uilabel(app.HomeTab);
            app.SubmittedbyNaveedAhmedLabel.Text = 'Submitted by Naveed Ahmed';
            app.INTERPOLATIONTab = uitab(app.TabGroup);
            app.INTERPOLATIONTab.Title = 'INTERPOLATION';
            app.NEARESTButton = uibutton(app.INTERPOLATIONTab, 'push');
            app.NEARESTButton.ButtonPushedFcn = createCallbackFcn(app, @NEARESTButtonPushed, true);
            app.NEARESTButton.Icon = 'Clock Image.PNG';
            app.NEARESTButton.Text = 'NEAREST';
            app.Image = uiimage(app.INTERPOLATIONTab);
            app.Image.ImageSource = 'Clock Image.PNG';
            app.AllOperationsCombinedButton = uibutton(app.INTERPOLATIONTab, 'push');
            app.AllOperationsCombinedButton.ButtonPushedFcn = createCallbackFcn(app, @AllOperationsCombinedButtonPushed, true);
            app.AllOperationsCombinedButton.Icon = 'Clock Image.PNG';
            app.AllOperationsCombinedButton.Text = 'All Operations Combined';
            app.BILINEARButton = uibutton(app.INTERPOLATIONTab, 'push');
            app.BILINEARButton.ButtonPushedFcn = createCallbackFcn(app, @BILINEARButtonPushed, true);
            app.BILINEARButton.Icon = 'Clock Image.PNG';
            app.BILINEARButton.Text = 'BILINEAR';
            app.BICUBICButton = uibutton(app.INTERPOLATIONTab, 'push');
            app.BICUBICButton.ButtonPushedFcn = createCallbackFcn(app, @BICUBICButtonPushed, true);
            app.BICUBICButton.Icon = 'Clock Image.PNG';
            app.BICUBICButton.Text = 'BICUBIC';
            app.Image3 = uiimage(app.INTERPOLATIONTab);
            app.Image3.ImageSource = 'coins-image.jpg';
            app.ADDNOISEButton = uibutton(app.INTERPOLATIONTab, 'push');
            app.ADDNOISEButton.ButtonPushedFcn = createCallbackFcn(app, @ADDNOISEButtonPushed, true);
            app.ADDNOISEButton.Icon = 'coins-image.jpg';
            app.ADDNOISEButton.Text = 'ADD NOISE';
            app.REMOVENOISEButton = uibutton(app.INTERPOLATIONTab, 'push');
            app.REMOVENOISEButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVENOISEButtonPushed, true);
            app.REMOVENOISEButton.Icon = 'coins-image.jpg';
            app.REMOVENOISEButton.Text = 'REMOVE NOISE';
            app.DigitalImageProcessiongLabel = uilabel(app.INTERPOLATIONTab);
            app.DigitalImageProcessiongLabel.Text = 'Digital Image Processiong';
            app.FILTERINGTab = uitab(app.TabGroup);
            app.FILTERINGTab.Title = 'FILTERING';
            app.FiltersCombinedButton = uibutton(app.FILTERINGTab, 'push');
            app.FiltersCombinedButton.ButtonPushedFcn = createCallbackFcn(app, @FiltersCombinedButtonPushed, true);
            app.FiltersCombinedButton.Icon = 'pout.jpg';
            app.FiltersCombinedButton.Text = 'Filters Combined';
            app.StrethcedImageButton = uibutton(app.FILTERINGTab, 'push');
            app.StrethcedImageButton.ButtonPushedFcn = createCallbackFcn(app, @StrethcedImageButtonPushed, true);
            app.StrethcedImageButton.Icon = 'pout.jpg';
            app.StrethcedImageButton.Text = 'Strethced Image';
            app.BoxFilterButton = uibutton(app.FILTERINGTab, 'push');
            app.BoxFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BoxFilterButtonPushed, true);
            app.BoxFilterButton.Icon = 'pout.jpg';
            app.BoxFilterButton.Text = 'Box Filter';
            app.WeightedFilterButton = uibutton(app.FILTERINGTab, 'push');
            app.WeightedFilterButton.ButtonPushedFcn = createCallbackFcn(app, @WeightedFilterButtonPushed, true);
            app.WeightedFilterButton.Icon = 'pout.jpg';
            app.WeightedFilterButton.Text = 'Weighted Filter';
            app.GaussianfilterButton = uibutton(app.FILTERINGTab, 'push');
            app.GaussianfilterButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianfilterButtonPushed, true);
            app.GaussianfilterButton.Icon = 'pout.jpg';
            app.GaussianfilterButton.Text = 'Gaussian filter';
            app.Image2 = uiimage(app.FILTERINGTab);
            app.Image2.ImageSource = 'pout.jpg';
            app.DigitalImageProcessiongLabel_2 = uilabel(app.FILTERINGTab);
            app.DigitalImageProcessiongLabel_2.Text = 'Digital Image Processiong';
            app.HISTOGRAMTab = uitab(app.TabGroup);
            app.HISTOGRAMTab.Title = 'HISTOGRAM';
            app.EQUALIZATIONButton = uibutton(app.HISTOGRAMTab, 'push');
            app.EQUALIZATIONButton.ButtonPushedFcn = createCallbackFcn(app, @EQUALIZATIONButtonPushed, true);
            app.EQUALIZATIONButton.Icon = 'tire-image.jpg';
            app.EQUALIZATIONButton.Text = 'EQUALIZATION';
            app.MATCHINGButton = uibutton(app.HISTOGRAMTab, 'push');
            app.MATCHINGButton.ButtonPushedFcn = createCallbackFcn(app, @MATCHINGButtonPushed, true);
            app.MATCHINGButton.Icon = 'tire-image.jpg';
            app.MATCHINGButton.Text = 'MATCHING';
            app.HISTOGRAMButton = uibutton(app.HISTOGRAMTab, 'push');
            app.HISTOGRAMButton.ButtonPushedFcn = createCallbackFcn(app, @HISTOGRAMButtonPushed, true);
            app.HISTOGRAMButton.Icon = 'tire-image.jpg';
            app.HISTOGRAMButton.Text = 'HISTOGRAM';
            app.Image4 = uiimage(app.HISTOGRAMTab);
            app.Image4.ImageSource = 'tire-image.jpg';
            app.AllOperationsButton = uibutton(app.HISTOGRAMTab, 'push');
            app.AllOperationsButton.ButtonPushedFcn = createCallbackFcn(app, @AllOperationsButtonPushed, true);
            app.AllOperationsButton.Icon = 'tire-image.jpg';
            app.AllOperationsButton.Text = 'All Operations';
            app.DigitalImageProcessiongLabel_3 = uilabel(app.HISTOGRAMTab);
            app.DigitalImageProcessiongLabel_3.Text = 'Digital Image Processiong';
            app.RESTORATIONTab = uitab(app.TabGroup);
            app.RESTORATIONTab.Title = 'RESTORATION';
            app.GaussianNoiseButton = uibutton(app.RESTORATIONTab, 'push');
            app.GaussianNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianNoiseButtonPushed, true);
            app.GaussianNoiseButton.Text = 'Gaussian Noise';
            app.Image6 = uiimage(app.RESTORATIONTab);
            app.Image6.ImageSource = 'cameraman.jpg';
            app.SaltandPepperButton = uibutton(app.RESTORATIONTab, 'push');
            app.SaltandPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltandPepperButtonPushed, true);
            app.SaltandPepperButton.Text = 'Salt and Pepper';
            app.RemoveNoiseButton = uibutton(app.RESTORATIONTab, 'push');
            app.RemoveNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNoiseButtonPushed, true);
            app.RemoveNoiseButton.Text = 'Remove Noise';
            app.DigitalImageProcessiongLabel_4 = uilabel(app.RESTORATIONTab);
            app.DigitalImageProcessiongLabel_4.Text = 'Digital Image Processiong';
            app.FREQUENCEANDSPATIALDOMAINTab = uitab(app.TabGroup);
            app.FREQUENCEANDSPATIALDOMAINTab.Title = 'FREQUENCE  AND   SPATIAL DOMAIN';
            app.SPATIALDOMAINButton = uibutton(app.FREQUENCEANDSPATIALDOMAINTab, 'push');
            app.SPATIALDOMAINButton.ButtonPushedFcn = createCallbackFcn(app, @SPATIALDOMAINButtonPushed, true);
            app.SPATIALDOMAINButton.Text = 'SPATIAL DOMAIN';
            app.LPFGAUSSIANButton = uibutton(app.FREQUENCEANDSPATIALDOMAINTab, 'push');
            app.LPFGAUSSIANButton.ButtonPushedFcn = createCallbackFcn(app, @LPFGAUSSIANButtonPushed, true);
            app.LPFGAUSSIANButton.Text = 'LPF GAUSSIAN';
            app.IMAGESHARPENINGButton = uibutton(app.FREQUENCEANDSPATIALDOMAINTab, 'push');
            app.IMAGESHARPENINGButton.ButtonPushedFcn = createCallbackFcn(app, @IMAGESHARPENINGButtonPushed, true);
            app.IMAGESHARPENINGButton.Text = 'IMAGE SHARPENING';
            app.Image5 = uiimage(app.FREQUENCEANDSPATIALDOMAINTab);
            app.Image5.ImageSource = 'football.PNG';
            app.LOGButton = uibutton(app.FREQUENCEANDSPATIALDOMAINTab, 'push');
            app.LOGButton.ButtonPushedFcn = createCallbackFcn(app, @LOGButtonPushed, true);
            app.LOGButton.Text = 'LOG';
            app.LowPassFiltersANDHighPassFiltersLabel = uilabel(app.FREQUENCEANDSPATIALDOMAINTab);
            app.LowPassFiltersANDHighPassFiltersLabel.Text = 'Low Pass Filters AND  High Pass Filters';
            app.Label = uilabel(app.FREQUENCEANDSPATIALDOMAINTab);
            app.Label.Text = '';
            app.AVERAGEButton = uibutton(app.FREQUENCEANDSPATIALDOMAINTab, 'push');
            app.AVERAGEButton.ButtonPushedFcn = createCallbackFcn(app, @AVERAGEButtonPushed, true);
            app.AVERAGEButton.Text = 'AVERAGE';
            app.DISKButton = uibutton(app.FREQUENCEANDSPATIALDOMAINTab, 'push');
            app.DISKButton.ButtonPushedFcn = createCallbackFcn(app, @DISKButtonPushed, true);
            app.DISKButton.Text = 'DISK';
            app.LAPLACIANButton = uibutton(app.FREQUENCEANDSPATIALDOMAINTab, 'push');
            app.LAPLACIANButton.ButtonPushedFcn = createCallbackFcn(app, @LAPLACIANButtonPushed, true);
            app.LAPLACIANButton.Text = 'LAPLACIAN';
            app.MOTIONButton = uibutton(app.FREQUENCEANDSPATIALDOMAINTab, 'push');
            app.MOTIONButton.ButtonPushedFcn = createCallbackFcn(app, @MOTIONButtonPushed, true);
            app.MOTIONButton.Text = 'MOTION';
            app.PREWITTButton = uibutton(app.FREQUENCEANDSPATIALDOMAINTab, 'push');
            app.PREWITTButton.ButtonPushedFcn = createCallbackFcn(app, @PREWITTButtonPushed, true);
            app.PREWITTButton.Text = 'PREWITT';
            app.SOBELButton = uibutton(app.FREQUENCEANDSPATIALDOMAINTab, 'push');
            app.SOBELButton.ButtonPushedFcn = createCallbackFcn(app, @SOBELButtonPushed, true);
            app.SOBELButton.Text = 'SOBEL';
            app.DigitalImageProcessiongLabel_5 = uilabel(app.FREQUENCEANDSPATIALDOMAINTab);
            app.DigitalImageProcessiongLabel_5.Text = 'Digital Image Processiong';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIPProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end